classdef GCS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatlabGCSUIFigure            matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        FlightData                   matlab.ui.container.Tab
        UAV1Panel                    matlab.ui.container.Panel
        AngleDeg_1                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel_6   matlab.ui.control.Label
        SpeedKmh_1                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel_6  matlab.ui.control.Label
        Lamp_1                       matlab.ui.control.Lamp
        UAV2Panel                    matlab.ui.container.Panel
        AngleDeg_2                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel_4   matlab.ui.control.Label
        SpeedKmh_2                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel_4  matlab.ui.control.Label
        Lamp_2                       matlab.ui.control.Lamp
        UAV3Panel                    matlab.ui.container.Panel
        AngleDeg_3                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel_5   matlab.ui.control.Label
        SpeedKmh_3                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel_5  matlab.ui.control.Label
        Lamp_3                       matlab.ui.control.Lamp
        UAV4Panel                    matlab.ui.container.Panel
        AngleDeg_4                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel_3   matlab.ui.control.Label
        SpeedKmh_4                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel_3  matlab.ui.control.Label
        Lamp_4                       matlab.ui.control.Lamp
        UAV5Panel                    matlab.ui.container.Panel
        AngleDeg_5                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel     matlab.ui.control.Label
        SpeedKmh_5                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel    matlab.ui.control.Label
        Lamp_5                       matlab.ui.control.Lamp
        UserPanel                    matlab.ui.container.Panel
        AngleDeg_0                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel_2   matlab.ui.control.Label
        SpeedKmh_0                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel_2  matlab.ui.control.Label
        Lamp_0                       matlab.ui.control.Lamp
        DebugPanel                   matlab.ui.container.Panel
        Switch                       matlab.ui.control.ToggleSwitch
        DataTextArea                 matlab.ui.control.TextArea
        ConfigTurning                matlab.ui.container.Tab
        SelectConfig                 matlab.ui.control.Button
        LoadConfig                   matlab.ui.control.Button
        ConfigFile                   matlab.ui.control.EditField
        Label_41                     matlab.ui.control.Label
        UAV5PanelConfig              matlab.ui.container.Panel
        UAV5Track                    matlab.ui.control.EditField
        Label_46                     matlab.ui.control.Label
        UAV5Goal                     matlab.ui.control.EditField
        Label_40                     matlab.ui.control.Label
        UAV5Centroid                 matlab.ui.control.EditField
        Label_39                     matlab.ui.control.Label
        UAV5Appearance               matlab.ui.control.EditField
        Label_38                     matlab.ui.control.Label
        UAV5TrajectoryLength         matlab.ui.control.NumericEditField
        Label_37                     matlab.ui.control.Label
        UAV5Color                    matlab.ui.control.EditField
        Label_36                     matlab.ui.control.Label
        UAV4PanelConfig              matlab.ui.container.Panel
        UAV4Track                    matlab.ui.control.EditField
        Label_45                     matlab.ui.control.Label
        UAV4Goal                     matlab.ui.control.EditField
        Label_35                     matlab.ui.control.Label
        UAV4Centroid                 matlab.ui.control.EditField
        Label_34                     matlab.ui.control.Label
        UAV4Appearance               matlab.ui.control.EditField
        Label_33                     matlab.ui.control.Label
        UAV4TrajectoryLength         matlab.ui.control.NumericEditField
        Label_32                     matlab.ui.control.Label
        UAV4Color                    matlab.ui.control.EditField
        Label_31                     matlab.ui.control.Label
        UAV3PanelConfig              matlab.ui.container.Panel
        UAV3Track                    matlab.ui.control.EditField
        Label_44                     matlab.ui.control.Label
        UAV3Goal                     matlab.ui.control.EditField
        Label_30                     matlab.ui.control.Label
        UAV3Centroid                 matlab.ui.control.EditField
        Label_29                     matlab.ui.control.Label
        UAV3Appearance               matlab.ui.control.EditField
        Label_28                     matlab.ui.control.Label
        UAV3TrajectoryLength         matlab.ui.control.NumericEditField
        Label_27                     matlab.ui.control.Label
        UAV3Color                    matlab.ui.control.EditField
        Label_26                     matlab.ui.control.Label
        UAV2PanelConfig              matlab.ui.container.Panel
        UAV2Track                    matlab.ui.control.EditField
        Label_43                     matlab.ui.control.Label
        UAV2Goal                     matlab.ui.control.EditField
        Label_25                     matlab.ui.control.Label
        UAV2Centroid                 matlab.ui.control.EditField
        Label_24                     matlab.ui.control.Label
        UAV2Appearance               matlab.ui.control.EditField
        Label_23                     matlab.ui.control.Label
        UAV2TrajectoryLength         matlab.ui.control.NumericEditField
        Label_22                     matlab.ui.control.Label
        UAV2Color                    matlab.ui.control.EditField
        Label_21                     matlab.ui.control.Label
        UAV1PanelConfig              matlab.ui.container.Panel
        UAV1Track                    matlab.ui.control.EditField
        Label_42                     matlab.ui.control.Label
        UAV1Goal                     matlab.ui.control.EditField
        Label_12                     matlab.ui.control.Label
        UAV1Centroid                 matlab.ui.control.EditField
        Label_11                     matlab.ui.control.Label
        UAV1Appearance               matlab.ui.control.EditField
        Label_10                     matlab.ui.control.Label
        UAV1TrajectoryLength         matlab.ui.control.NumericEditField
        Label_5                      matlab.ui.control.Label
        UAV1Color                    matlab.ui.control.EditField
        Label_3                      matlab.ui.control.Label
        SimuPanel                    matlab.ui.container.Panel
        MapDisplay                   matlab.ui.control.DropDown
        Label_17                     matlab.ui.control.Label
        CentroidDisplay              matlab.ui.control.DropDown
        Label_16                     matlab.ui.control.Label
        GoalDisplay                  matlab.ui.control.DropDown
        Label_15                     matlab.ui.control.Label
        TrackDisplay                 matlab.ui.control.DropDown
        Label_14                     matlab.ui.control.Label
        RemoteDisplay                matlab.ui.control.DropDown
        Label_13                     matlab.ui.control.Label
        FormationType                matlab.ui.control.DropDown
        Label_9                      matlab.ui.control.Label
        StepFrequency                matlab.ui.control.NumericEditField
        Label_8                      matlab.ui.control.Label
        StepSpeed                    matlab.ui.control.NumericEditField
        Label_6                      matlab.ui.control.Label
        FinalTime                    matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Simulator % use which simulator
        SimuFigure % Description
        SimuAxes
    end
    
    methods (Access = private)
        
        function SetSimuFigAx(app)
            app.SimuFigure = app.MatlabGCSUIFigure;
            app.SimuAxes = app.UIAxes;
        end
        
        function setAngleSpeed(app,id)
            switch id
                case 1
                    AngleDeg = app.AngleDeg_1;
                    SpeedKmh = app.SpeedKmh_1;
                case 2
                    AngleDeg = app.AngleDeg_2;
                    SpeedKmh = app.SpeedKmh_2;
                case 3
                    AngleDeg = app.AngleDeg_3;
                    SpeedKmh = app.SpeedKmh_3;
                case 4
                    AngleDeg = app.AngleDeg_4;
                    SpeedKmh = app.SpeedKmh_4;
                case 5
                    AngleDeg = app.AngleDeg_4;
                    SpeedKmh = app.SpeedKmh_4;
                otherwise
                    AngleDeg = app.AngleDeg_0;
                    SpeedKmh = app.SpeedKmh_0;
            end
            if id==1||id==2||id==3||id==4||id==5
                AngleDeg.Value = -app.Simulator.model.model(id).q(3)/pi*180+90;
                if AngleDeg.Value>360
                    AngleDeg.Value = AngleDeg.Value-360;
                end
                if AngleDeg.Value<0
                    AngleDeg.Value = AngleDeg.Value+360;
                end
                SpeedKmh.Value = app.Simulator.model.model(id).v*60*6;
            else
                pr_dot_ave = sum(app.Simulator.model.percept.pr_dot,2)/app.Simulator.model.n;
                AngleDeg.Value = atan2(pr_dot_ave(1),pr_dot_ave(2))/pi*180;
                SpeedKmh.Value = norm(pr_dot_ave)*60*6;
            end
        end
        
        function setLampColor(app,id,cl)
            switch id
                case 1
                    Lamp = app.Lamp_1;
                case 2
                    Lamp = app.Lamp_2;
                case 3
                    Lamp = app.Lamp_3;
                case 4
                    Lamp = app.Lamp_4;
                case 5
                    Lamp = app.Lamp_5;
                otherwise
                    Lamp = app.Lamp_0;
            end
            switch cl
                case 'r'
                    LampColor = [1 0 0];
                case 'g'
                    LampColor = [0 1 0];
                case 'b'
                    LampColor = [0 0 1];
                case 'c'
                    LampColor = [0 1 1];
                case 'm'
                    LampColor = [1 0 1];
                case 'y'
                    LampColor = [1 1/2 0];
                otherwise
                    LampColor = [1 1 1]/2;
            end
            Lamp.Color = LampColor;
        end
        
        function readConfig(app)
            % simu panel
            app.StepFrequency.Value = 1/(app.Simulator.dt*10);
            app.FinalTime.Value = app.Simulator.T*10;
            app.StepSpeed.Value = app.Simulator.speed;
            app.FormationType.Value = app.FormationType.Items{app.Simulator.model.percept.f_curr};
            app.RemoteDisplay.Value = app.RemoteDisplay.Items{GCS.YesOrNo(~isempty(app.Simulator.model.percept.joy))};
            app.TrackDisplay.Value = app.TrackDisplay.Items{GCS.YesOrNo(app.Simulator.model.model(1).percept.pr_visible)};
            app.GoalDisplay.Value = app.GoalDisplay.Items{GCS.YesOrNo(app.Simulator.model.percept.pr_visible)};
            app.CentroidDisplay.Value = app.CentroidDisplay.Items{GCS.YesOrNo(app.Simulator.model.percept.pc_visible)};
            % uav panel config
            for i=1:app.Simulator.model.n
                app.("UAV"+num2str(i)+"Color").Value = app.Simulator.model.model(i).color;
                app.("UAV"+num2str(i)+"TrajectoryLength").Value = app.Simulator.model.model(i).ntrj;
                app.("UAV"+num2str(i)+"Appearance").Value = app.Simulator.model.model(i).appear;
                app.("UAV"+num2str(i)+"Centroid").Value = app.Simulator.model.percept.appear(2);
                app.("UAV"+num2str(i)+"Goal").Value = app.Simulator.model.percept.appear(1);
                app.("UAV"+num2str(i)+"Track").Value = app.Simulator.model.model(i).percept.appear;
                app.("SpeedKmh_"+num2str(i)).Limits = [app.Simulator.model.model(i).v_min,app.Simulator.model.model(i).v_max]*360;
                app.("SpeedKmh_"+num2str(i)).ScaleColorLimits = app.("SpeedKmh_"+num2str(i)).ScaleColorLimits/400*app.("SpeedKmh_"+num2str(i)).Limits(2);
            end 
        end

        function setConfig(app)
            % simu panel
            app.Simulator.dt = (1/app.StepFrequency.Value)/10;
            app.Simulator.T = app.FinalTime.Value/10;
            app.Simulator.speed = app.StepSpeed.Value;
            [~, col] = find(cellfun(@(x) strcmp(x,app.FormationType.Value),app.FormationType.Items));
            app.Simulator.model.percept.f_curr = col;
            % uav panel config
            for i=1:app.Simulator.model.n
                app.Simulator.model.model(i).color = app.("UAV"+num2str(i)+"Color").Value;
                app.Simulator.model.model(i).ntrj = app.("UAV"+num2str(i)+"TrajectoryLength").Value;
            end
        end
    end

    methods (Static)
        function results = YesOrNo(inputs)
            % 1,0 => 1:2
            % 1,2 => 1,0
            results = 2-inputs;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            LoadConfigButtonPushed(app);
        end

        % Value changed function: Switch
        function StartDebugButtonPushed(app, event)
            if app.Switch.Value=='开'
                % set fig and ax
                app.SetSimuFigAx();
                % init config
                app.setConfig();
                % start loop
                while app.Simulator.t0<=app.Simulator.T
                    tic;
                    % simulator mascot run
                    app.Simulator.show(app);
                    % set angle, speed and lamp
                    for i=0:app.Simulator.model.n
                        app.setAngleSpeed(i);
                        if i==0
                            app.setLampColor(i,'y');
                        else
                            app.setLampColor(i,app.Simulator.model.color(i));
                        end
                    end
                    % update simulator time
                    app.Simulator.t0 = app.Simulator.t0+app.Simulator.dt;
                    % close simulator and save current config
                    if app.Simulator.model.percept.stop||app.Switch.Value=='关'
                        app.Switch.Value = '关';
                        app.readConfig();
                        break;
                    end
                    % data display
                    data = [{['algo: ' app.Simulator.model.algorithm]},{['time: ' app.Simulator.second_change(app.Simulator.t0*10)]}];
                    app.DataTextArea.Value = data;
                    % reset config in realtime
                    app.setConfig();
                    % draw now
                    t_loop = toc;
                    pause((app.Simulator.dt*10-t_loop)/app.Simulator.speed);
                end
            end
        end

        % Button pushed function: LoadConfig
        function LoadConfigButtonPushed(app, event)
            delete(app.Simulator);
            app.Simulator = eval(app.ConfigFile.Value+"()");
            app.readConfig();
            % set simulation
            app.SetSimuFigAx();
            for i=0:5
                app.setLampColor(i,'k');
            end
            % run simulation
            app.Simulator.run(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatlabGCSUIFigure = uifigure('Visible', 'off');
            app.MatlabGCSUIFigure.Name = 'Matlab GCS';
            app.UIAxes = uiaxes(app.MatlabGCSUIFigure);
            title(app.UIAxes, '地图')
            xlabel(app.UIAxes, '东（km）')
            ylabel(app.UIAxes, '北（km）')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.MatlabGCSUIFigure);
            app.FlightData = uitab(app.TabGroup);
            app.FlightData.Title = '飞行数据';
            app.DebugPanel = uipanel(app.FlightData);
            app.DebugPanel.Title = '信息中心';
            app.DataTextArea = uitextarea(app.DebugPanel);
            app.Switch = uiswitch(app.DebugPanel, 'toggle');
            app.Switch.Items = {'关', '开'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @StartDebugButtonPushed, true);
            app.Switch.Value = '关';
            app.UserPanel = uipanel(app.FlightData);
            app.UserPanel.Title = '遥控/路径规划';
            app.Lamp_0 = uilamp(app.UserPanel);
            app.kmhAirspeedIndicatorLabel_2 = uilabel(app.UserPanel);
            app.kmhAirspeedIndicatorLabel_2.Text = '速度（km/h）';
            app.SpeedKmh_0 = uiaeroairspeed(app.UserPanel);
            app.degHeadingIndicatorLabel_2 = uilabel(app.UserPanel);
            app.degHeadingIndicatorLabel_2.Text = '航向角（deg）';
            app.AngleDeg_0 = uiaeroheading(app.UserPanel);
            app.UAV5Panel = uipanel(app.FlightData);
            app.UAV5Panel.Title = 'UAV5';
            app.Lamp_5 = uilamp(app.UAV5Panel);
            app.kmhAirspeedIndicatorLabel = uilabel(app.UAV5Panel);
            app.kmhAirspeedIndicatorLabel.Text = '速度（km/h）';
            app.SpeedKmh_5 = uiaeroairspeed(app.UAV5Panel);
            app.degHeadingIndicatorLabel = uilabel(app.UAV5Panel);
            app.degHeadingIndicatorLabel.Text = '航向角（deg）';
            app.AngleDeg_5 = uiaeroheading(app.UAV5Panel);
            app.UAV4Panel = uipanel(app.FlightData);
            app.UAV4Panel.Title = 'UAV4';
            app.Lamp_4 = uilamp(app.UAV4Panel);
            app.kmhAirspeedIndicatorLabel_3 = uilabel(app.UAV4Panel);
            app.kmhAirspeedIndicatorLabel_3.Text = '速度（km/h）';
            app.SpeedKmh_4 = uiaeroairspeed(app.UAV4Panel);
            app.degHeadingIndicatorLabel_3 = uilabel(app.UAV4Panel);
            app.degHeadingIndicatorLabel_3.Text = '航向角（deg）';
            app.AngleDeg_4 = uiaeroheading(app.UAV4Panel);
            app.UAV3Panel = uipanel(app.FlightData);
            app.UAV3Panel.Title = 'UAV3';
            app.Lamp_3 = uilamp(app.UAV3Panel);
            app.kmhAirspeedIndicatorLabel_5 = uilabel(app.UAV3Panel);
            app.kmhAirspeedIndicatorLabel_5.Text = '速度（km/h）';
            app.SpeedKmh_3 = uiaeroairspeed(app.UAV3Panel);
            app.degHeadingIndicatorLabel_5 = uilabel(app.UAV3Panel);
            app.degHeadingIndicatorLabel_5.Text = '航向角（deg）';
            app.AngleDeg_3 = uiaeroheading(app.UAV3Panel);
            app.UAV2Panel = uipanel(app.FlightData);
            app.UAV2Panel.Title = 'UAV2';
            app.Lamp_2 = uilamp(app.UAV2Panel);
            app.kmhAirspeedIndicatorLabel_4 = uilabel(app.UAV2Panel);
            app.kmhAirspeedIndicatorLabel_4.Text = '速度（km/h）';
            app.SpeedKmh_2 = uiaeroairspeed(app.UAV2Panel);
            app.degHeadingIndicatorLabel_4 = uilabel(app.UAV2Panel);
            app.degHeadingIndicatorLabel_4.Text = '航向角（deg）';
            app.AngleDeg_2 = uiaeroheading(app.UAV2Panel);
            app.UAV1Panel = uipanel(app.FlightData);
            app.UAV1Panel.Title = 'UAV1';
            app.Lamp_1 = uilamp(app.UAV1Panel);
            app.kmhAirspeedIndicatorLabel_6 = uilabel(app.UAV1Panel);
            app.kmhAirspeedIndicatorLabel_6.Text = '速度（km/h）';
            app.SpeedKmh_1 = uiaeroairspeed(app.UAV1Panel);
            app.degHeadingIndicatorLabel_6 = uilabel(app.UAV1Panel);
            app.degHeadingIndicatorLabel_6.Text = '航向角（deg）';
            app.AngleDeg_1 = uiaeroheading(app.UAV1Panel);
            app.ConfigTurning = uitab(app.TabGroup);
            app.ConfigTurning.Title = '参数设置';
            app.SimuPanel = uipanel(app.ConfigTurning);
            app.SimuPanel.Title = '仿真/实验设置';
            app.Label_2 = uilabel(app.SimuPanel);
            app.Label_2.Text = '时长（ s ）';
            app.FinalTime = uieditfield(app.SimuPanel, 'numeric');
            app.FinalTime.Limits = [0 Inf];
            app.FinalTime.Value = 60;
            app.Label_6 = uilabel(app.SimuPanel);
            app.Label_6.Text = '加速（倍）';
            app.StepSpeed = uieditfield(app.SimuPanel, 'numeric');
            app.StepSpeed.Limits = [0.1 10];
            app.StepSpeed.Value = 1;
            app.Label_8 = uilabel(app.SimuPanel);
            app.Label_8.Text = '频率（Hz）';
            app.StepFrequency = uieditfield(app.SimuPanel, 'numeric');
            app.StepFrequency.Limits = [0.1 100];
            app.StepFrequency.Value = 5;
            app.Label_9 = uilabel(app.SimuPanel);
            app.Label_9.Text = '队形选择';
            app.FormationType = uidropdown(app.SimuPanel);
            app.FormationType.Items = {'菱形', '横一字', '竖一字', '平四梯', '梯形', '平四楔', '左楔形', '右楔形', '正方形'};
            app.FormationType.Value = '菱形';
            app.Label_13 = uilabel(app.SimuPanel);
            app.Label_13.Text = '使用遥控';
            app.RemoteDisplay = uidropdown(app.SimuPanel);
            app.RemoteDisplay.Items = {'是', '否'};
            app.RemoteDisplay.Value = '否';
            app.Label_14 = uilabel(app.SimuPanel);
            app.Label_14.Text = '显示跟踪';
            app.TrackDisplay = uidropdown(app.SimuPanel);
            app.TrackDisplay.Items = {'是', '否'};
            app.TrackDisplay.Value = '否';
            app.Label_15 = uilabel(app.SimuPanel);
            app.Label_15.Text = '显示终点';
            app.GoalDisplay = uidropdown(app.SimuPanel);
            app.GoalDisplay.Items = {'是', '否'};
            app.GoalDisplay.Value = '否';
            app.Label_16 = uilabel(app.SimuPanel);
            app.Label_16.Text = '显示质心';
            app.CentroidDisplay = uidropdown(app.SimuPanel);
            app.CentroidDisplay.Items = {'是', '否'};
            app.CentroidDisplay.Value = '否';
            app.Label_17 = uilabel(app.SimuPanel);
            app.Label_17.Text = '显示地图';
            app.MapDisplay = uidropdown(app.SimuPanel);
            app.MapDisplay.Items = {'是', '否'};
            app.MapDisplay.Value = '否';
            app.UAV1PanelConfig = uipanel(app.ConfigTurning);
            app.UAV1PanelConfig.Title = 'UAV1设置';
            app.Label_3 = uilabel(app.UAV1PanelConfig);
            app.Label_3.Text = '颜色';
            app.UAV1Color = uieditfield(app.UAV1PanelConfig, 'text');
            app.UAV1Color.Value = 'r';
            app.Label_5 = uilabel(app.UAV1PanelConfig);
            app.Label_5.Text = '轨迹';
            app.UAV1TrajectoryLength = uieditfield(app.UAV1PanelConfig, 'numeric');
            app.UAV1TrajectoryLength.Limits = [0 1000];
            app.UAV1TrajectoryLength.Value = 300;
            app.Label_10 = uilabel(app.UAV1PanelConfig);
            app.Label_10.Text = '外观';
            app.UAV1Appearance = uieditfield(app.UAV1PanelConfig, 'text');
            app.UAV1Appearance.Value = 'o';
            app.Label_11 = uilabel(app.UAV1PanelConfig);
            app.Label_11.Text = '质心';
            app.UAV1Centroid = uieditfield(app.UAV1PanelConfig, 'text');
            app.UAV1Centroid.Value = 'p';
            app.Label_12 = uilabel(app.UAV1PanelConfig);
            app.Label_12.Text = '终点';
            app.UAV1Goal = uieditfield(app.UAV1PanelConfig, 'text');
            app.UAV1Goal.Value = 's';
            app.Label_42 = uilabel(app.UAV1PanelConfig);
            app.Label_42.Text = '跟踪';
            app.UAV1Track = uieditfield(app.UAV1PanelConfig, 'text');
            app.UAV1Track.Value = '*';
            app.UAV2PanelConfig = uipanel(app.ConfigTurning);
            app.UAV2PanelConfig.Title = 'UAV2设置';
            app.Label_21 = uilabel(app.UAV2PanelConfig);
            app.Label_21.Text = '颜色';
            app.UAV2Color = uieditfield(app.UAV2PanelConfig, 'text');
            app.UAV2Color.Value = 'r';
            app.Label_22 = uilabel(app.UAV2PanelConfig);
            app.Label_22.Text = '轨迹';
            app.UAV2TrajectoryLength = uieditfield(app.UAV2PanelConfig, 'numeric');
            app.UAV2TrajectoryLength.Limits = [0 1000];
            app.UAV2TrajectoryLength.Value = 300;
            app.Label_23 = uilabel(app.UAV2PanelConfig);
            app.Label_23.Text = '外观';
            app.UAV2Appearance = uieditfield(app.UAV2PanelConfig, 'text');
            app.UAV2Appearance.Value = 'o';
            app.Label_24 = uilabel(app.UAV2PanelConfig);
            app.Label_24.Text = '质心';
            app.UAV2Centroid = uieditfield(app.UAV2PanelConfig, 'text');
            app.UAV2Centroid.Value = 'p';
            app.Label_25 = uilabel(app.UAV2PanelConfig);
            app.Label_25.Text = '终点';
            app.UAV2Goal = uieditfield(app.UAV2PanelConfig, 'text');
            app.UAV2Goal.Value = 's';
            app.Label_43 = uilabel(app.UAV2PanelConfig);
            app.Label_43.Text = '跟踪';
            app.UAV2Track = uieditfield(app.UAV2PanelConfig, 'text');
            app.UAV2Track.Value = '*';
            app.UAV3PanelConfig = uipanel(app.ConfigTurning);
            app.UAV3PanelConfig.Title = 'UAV3设置';
            app.Label_26 = uilabel(app.UAV3PanelConfig);
            app.Label_26.Text = '颜色';
            app.UAV3Color = uieditfield(app.UAV3PanelConfig, 'text');
            app.UAV3Color.Value = 'r';
            app.Label_27 = uilabel(app.UAV3PanelConfig);
            app.Label_27.Text = '轨迹';
            app.UAV3TrajectoryLength = uieditfield(app.UAV3PanelConfig, 'numeric');
            app.UAV3TrajectoryLength.Limits = [0 1000];
            app.UAV3TrajectoryLength.Value = 300;
            app.Label_28 = uilabel(app.UAV3PanelConfig);
            app.Label_28.Text = '外观';
            app.UAV3Appearance = uieditfield(app.UAV3PanelConfig, 'text');
            app.UAV3Appearance.Value = 'o';
            app.Label_29 = uilabel(app.UAV3PanelConfig);
            app.Label_29.Text = '质心';
            app.UAV3Centroid = uieditfield(app.UAV3PanelConfig, 'text');
            app.UAV3Centroid.Value = 'p';
            app.Label_30 = uilabel(app.UAV3PanelConfig);
            app.Label_30.Text = '终点';
            app.UAV3Goal = uieditfield(app.UAV3PanelConfig, 'text');
            app.UAV3Goal.Value = 's';
            app.Label_44 = uilabel(app.UAV3PanelConfig);
            app.Label_44.Text = '跟踪';
            app.UAV3Track = uieditfield(app.UAV3PanelConfig, 'text');
            app.UAV3Track.Value = '*';
            app.UAV4PanelConfig = uipanel(app.ConfigTurning);
            app.UAV4PanelConfig.Title = 'UAV4设置';
            app.Label_31 = uilabel(app.UAV4PanelConfig);
            app.Label_31.Text = '颜色';
            app.UAV4Color = uieditfield(app.UAV4PanelConfig, 'text');
            app.UAV4Color.Value = 'r';
            app.Label_32 = uilabel(app.UAV4PanelConfig);
            app.Label_32.Text = '轨迹';
            app.UAV4TrajectoryLength = uieditfield(app.UAV4PanelConfig, 'numeric');
            app.UAV4TrajectoryLength.Limits = [0 1000];
            app.UAV4TrajectoryLength.Value = 300;
            app.Label_33 = uilabel(app.UAV4PanelConfig);
            app.Label_33.Text = '外观';
            app.UAV4Appearance = uieditfield(app.UAV4PanelConfig, 'text');
            app.UAV4Appearance.Value = 'o';
            app.Label_34 = uilabel(app.UAV4PanelConfig);
            app.Label_34.Text = '质心';
            app.UAV4Centroid = uieditfield(app.UAV4PanelConfig, 'text');
            app.UAV4Centroid.Value = 'p';
            app.Label_35 = uilabel(app.UAV4PanelConfig);
            app.Label_35.Text = '终点';
            app.UAV4Goal = uieditfield(app.UAV4PanelConfig, 'text');
            app.UAV4Goal.Value = 's';
            app.Label_45 = uilabel(app.UAV4PanelConfig);
            app.Label_45.Text = '跟踪';
            app.UAV4Track = uieditfield(app.UAV4PanelConfig, 'text');
            app.UAV4Track.Value = '*';
            app.UAV5PanelConfig = uipanel(app.ConfigTurning);
            app.UAV5PanelConfig.Title = 'UAV5设置';
            app.Label_36 = uilabel(app.UAV5PanelConfig);
            app.Label_36.Text = '颜色';
            app.UAV5Color = uieditfield(app.UAV5PanelConfig, 'text');
            app.UAV5Color.Value = 'r';
            app.Label_37 = uilabel(app.UAV5PanelConfig);
            app.Label_37.Text = '轨迹';
            app.UAV5TrajectoryLength = uieditfield(app.UAV5PanelConfig, 'numeric');
            app.UAV5TrajectoryLength.Limits = [0 1000];
            app.UAV5TrajectoryLength.Value = 300;
            app.Label_38 = uilabel(app.UAV5PanelConfig);
            app.Label_38.Text = '外观';
            app.UAV5Appearance = uieditfield(app.UAV5PanelConfig, 'text');
            app.UAV5Appearance.Value = 'o';
            app.Label_39 = uilabel(app.UAV5PanelConfig);
            app.Label_39.Text = '质心';
            app.UAV5Centroid = uieditfield(app.UAV5PanelConfig, 'text');
            app.UAV5Centroid.Value = 'p';
            app.Label_40 = uilabel(app.UAV5PanelConfig);
            app.Label_40.Text = '终点';
            app.UAV5Goal = uieditfield(app.UAV5PanelConfig, 'text');
            app.UAV5Goal.Value = 's';
            app.Label_46 = uilabel(app.UAV5PanelConfig);
            app.Label_46.Text = '跟踪';
            app.UAV5Track = uieditfield(app.UAV5PanelConfig, 'text');
            app.UAV5Track.Value = '*';
            app.Label_41 = uilabel(app.ConfigTurning);
            app.Label_41.Text = '配置文件';
            app.ConfigFile = uieditfield(app.ConfigTurning, 'text');
            app.ConfigFile.Value = 'gcs_simu';
            app.LoadConfig = uibutton(app.ConfigTurning, 'push');
            app.LoadConfig.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfig.Text = '加载';
            app.SelectConfig = uibutton(app.ConfigTurning, 'push');
            app.SelectConfig.Text = '选择';
            % Show the figure after all components are created
            app.MatlabGCSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GCS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatlabGCSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatlabGCSUIFigure)
        end
    end
end