classdef Mascot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MascotUIFigure            matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SimulationTab             matlab.ui.container.Tab
        InitializationPanel       matlab.ui.container.Panel
        RobotNumberDropDownLabel  matlab.ui.control.Label
        RobotNumberDropDown       matlab.ui.control.DropDown
        InitializeButton          matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        OptionsPanel              matlab.ui.container.Panel
        SaveDataCheckBox          matlab.ui.control.CheckBox
        UseBuiltinAxisCheckBox    matlab.ui.control.CheckBox
        SpeedEditFieldLabel       matlab.ui.control.Label
        SpeedEditField            matlab.ui.control.NumericEditField
        dtEditFieldLabel          matlab.ui.control.Label
        dtEditField               matlab.ui.control.NumericEditField
        StartButton               matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        ClearButton               matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        ScaleSliderLabel          matlab.ui.control.Label
        ScaleSlider               matlab.ui.control.Slider
        DirectionSliderLabel      matlab.ui.control.Label
        DirectionSlider           matlab.ui.control.Slider
        StateButtonGroup          matlab.ui.container.ButtonGroup
        SimulateButton            matlab.ui.control.ToggleButton
        CompareButton             matlab.ui.control.ToggleButton
        Log                       matlab.ui.control.TextArea
        SettingsTab               matlab.ui.container.Tab
        ExperimentsButtonGroup    matlab.ui.container.ButtonGroup
        TrajectoryTrackingButton  matlab.ui.control.RadioButton
        CollisionAvoidanceButton  matlab.ui.control.RadioButton
        FormationManeuverButton   matlab.ui.control.RadioButton
        GraphsButtonGroup         matlab.ui.container.ButtonGroup
        LaplacianButton           matlab.ui.control.RadioButton
        StressmatrixButton        matlab.ui.control.RadioButton
        PlantsButtonGroup         matlab.ui.container.ButtonGroup
        IntegratorButton          matlab.ui.control.RadioButton
        ManipulatorButton         matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        robot_num % robot number
        form_scale % formation scale
        form_dir % formation direction
        src_path % script path
        data_path % data path
        select_exp % select experiments
        is_debugged = false % debug flag: use outside axis for debug
        data_saved = false % if data saved
        set_pushed = false % if set button pushed
        initialize_pushed = false % if initialize button pushed
        scale_changed = false % if formation scale changed
        dir_changed = false % if formation direction changed
        select_sim % select from simulation and comparison
        select_gph % select from laplacian and stress matrix
        select_plt % select plant from integrator and  manipulator
        scene_handle % scene handle
        is_stopped = false % stop flag
        is_cleared = false % clear flag
    end
    
    methods (Access = private)
        function is_broken = Simulate(app)
            cd(fileparts(mfilename('fullpath')));
            settings = load([app.data_path '/settings']);
            initials = load([app.data_path '/initials']);
            % initials
            pos_traj_ref = initials.pos_traj_ref;
            pos_traj_hat = initials.pos_traj_hat;
            pos_end = initials.pos_end;
            var_state = initials.var_state;
            var_scale = kron(ones(settings.robot_num,1),settings.scale);
            var_dir = kron(ones(settings.robot_num,1),settings.dir);  
            % zero velocities
            dot_var_state = zeros(size(var_state));
            % lagrangian
            lambda = [];
            % simulation loop
            loop = 0;
            is_broken = false;
            t = settings.exp.time(1);
            while t <= settings.exp.time(end)
                loop = loop+1;
                % changed options
                if app.scale_changed
                    settings.scale = app.ScaleSlider.Value;
                    app.scale_changed = false;
                end
                if app.dir_changed
                    settings.dir = app.DirectionSlider.Value;
                    app.dir_changed = false;
                end
                playspeed = app.SpeedEditField.Value;
                interval = app.dtEditField.Value;
                % robot controller
                if app.select_sim==1
                    [data(loop), result, is_broken] = app.robotSimulate(pos_traj_ref,pos_traj_hat,pos_end,...
                        var_state,var_scale,var_dir,dot_var_state,lambda,playspeed,interval,loop,t,settings,is_broken);
                else
                    [data(loop), result, is_broken] = app.robotCompare(pos_traj_ref,pos_traj_hat,pos_end,...
                        var_state,var_scale,var_dir,dot_var_state,lambda,playspeed,interval,loop,t,settings,is_broken);
                end
                % show result
                if ~isempty(result)
                    app.Log.Value = result;
                end
                % restore data
                pos_traj_ref = data(loop).pos_traj_ref;
                pos_traj_hat = data(loop).pos_traj_hat;
                pos_end = data(loop).pos_end;
                var_state = data(loop).var_state;
                var_scale = data(loop).var_scale;
                var_dir = data(loop).var_dir;
                dot_var_state = data(loop).dot_var_state;
                lambda = data(loop).lambda;
                % next loop
                t = t+interval;
                drawnow
                if app.is_stopped
                    app.Log.Value = "Simulation stopped.";
                    uiwait(msgbox('Press OK to continue.'));
                    app.Log.Value = "Simulation continues.";
                    app.is_stopped = false;
                end
                if app.is_cleared
                    is_broken = true;
                    break
                end
            end
            % data to mat
            if app.data_saved
                clearvars app t i
                save([app.data_path '/simulation']);
            end
        end


        function mascotInitialize(app)
            % plant parameters
            plt_params = {app.robot_num,app.form_scale,app.form_dir,...
                    app.select_exp,app.select_gph,app.data_path,app.is_debugged};
            switch app.select_plt
                case 1
                    integratorMascot(plt_params{:});
                case 2
                    manipulatorMascot(plt_params{:});
                otherwise
                    app.Log.Value = "Please select another plant.";
            end
        end
        
        function scene = robotInitialize(app)
            % initialization parameters
            init_params = {app.UIAxes,app.data_path,app.is_debugged};
            switch app.select_plt
                case 1
                    scene = integratorInitialize(init_params{:});
                case 2
                    scene = manipulatorInitialize(init_params{:});
                otherwise
                    app.Log.Value = "Please select another plant.";
            end
        end
        
        function [data, result, is_broken] = robotSimulate(app,pos_traj_ref,pos_traj_hat,pos_end,...
                var_state,var_scale,var_dir,dot_var_state,lambda,playspeed,interval,loop,t,settings,is_broken)
            % simulation parameters
            sim_params = {app.scene_handle,pos_traj_ref,pos_traj_hat,pos_end,...
                var_state,var_scale,var_dir,dot_var_state,lambda,playspeed,interval,loop,t,settings};
            switch app.select_plt
                case 1
                    [data, result] = integratorSimulate(sim_params{:});
                case 2
                    [data, result] = manipulatorSimulate(sim_params{:});
                otherwise
                    app.Log.Value = "Please select another plant.";
            end
        end
        
        function [data, result, is_broken] = robotCompare(app,pos_traj_ref,pos_traj_hat,pos_end,...
                var_state,var_scale,var_dir,dot_var_state,lambda,playspeed,interval,loop,t,settings,is_broken)
            % comparison parameters
            com_params = {pos_traj_ref,pos_traj_hat,pos_end,...
                var_state,var_scale,var_dir,dot_var_state,lambda,playspeed,interval,loop,t,settings};
            switch app.select_plt
                case 1
                    [data, result] = integratorCompare(com_params{:});
                case 2
                    [data, result] = manipulatorCompare(com_params{:});
                otherwise
                    app.Log.Value = "Please select another plant.";
            end
        end
    end
    

    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            close all
            disp(fileparts(mfilename('fullpath')));
            cd(fileparts(mfilename('fullpath')));
            plt_index = app.PlantsButtonGroup.Children;
            app.select_plt = length(plt_index)-find([plt_index.Value]==1)+1;
            app.data_path = ['./data/' app.PlantsButtonGroup.Children([plt_index.Value]==1).Text];
            if ~isdeployed
                addpath(genpath('./scripts/'))
            end
            app.robot_num = str2double(app.RobotNumberDropDown.Value);
            app.form_scale = app.ScaleSlider.Value;
            app.form_dir = app.DirectionSlider.Value;
            exp_index = app.ExperimentsButtonGroup.Children;
            app.select_exp = length(exp_index)-find([exp_index.Value]==1)+1;
            gph_index = app.GraphsButtonGroup.Children;
            app.select_gph = length(gph_index)-find([gph_index.Value]==1)+1;
            app.is_debugged = ~app.UseBuiltinAxisCheckBox.Value;
            app.Log.Value = "Save setting data...";
            app.mascotInitialize();
            app.Log.Value = "Setting finished.";
            app.set_pushed = true;
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            close all
            cd(fileparts(mfilename('fullpath')));
            if ~exist([app.data_path '/settings.mat'],'file')
                app.Log.Value = "Please set parameters.";
            else
                if app.set_pushed
                    app.is_debugged = ~app.UseBuiltinAxisCheckBox.Value;
                    app.Log.Value = "Initialize robots and object...";
                    app.scene_handle = app.robotInitialize();
                    app.Log.Value = "Initialization finished.";
                else
                    app.Log.Value = "Please push set button first.";
                end
            end
            app.initialize_pushed = true;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cd(fileparts(mfilename('fullpath')));
            if ~exist([app.data_path '/initials.mat'],'file')
                app.Log.Value = "Please initialize robots and object.";
            else
                if app.set_pushed&&app.initialize_pushed
                    app.Log.Value = "Simulating...";
                    sim_index = app.StateButtonGroup.Children;
                    app.select_sim = length(sim_index)-find([sim_index.Value]==1)+1;
                    app.data_saved = app.SaveDataCheckBox.Value;
                    app.is_debugged = ~app.UseBuiltinAxisCheckBox.Value;
                    app.is_stopped = false;
                    app.is_cleared = false;
                    is_broken = app.Simulate();
                    if ~is_broken
                        app.Log.Value = "Simulation finished.";
                    end
                else
                    app.Log.Value = "Please push initialize button first.";
                end
            end
        end

        % Value changed function: RobotNumberDropDown
        function RobotNumberDropDownValueChanged(app, event)
            app.set_pushed = false;
            app.initialize_pushed = false;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if ~exist([app.data_path '/settings.mat'],'file')
                app.Log.Value = "Please set parameters.";
            else
                app.ScaleSlider.Value = app.form_scale;
                app.DirectionSlider.Value = app.form_dir;
                load([app.data_path '/settings.mat'],'exp');
                if app.is_debugged
                    axis(gca,exp.lim);
                else
                    axis(app.UIAxes,exp.lim);
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.is_cleared = true;
            if app.is_debugged
                cla(gca)
            else
                cla(app.UIAxes)
            end
            app.Log.Value = "Axes cleared.";
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.is_stopped = true;
        end

        % Selection changed function: ExperimentsButtonGroup
        function ExperimentsButtonGroupSelectionChanged(app, event)
            app.set_pushed = false;
            app.initialize_pushed = false;
        end

        % Value changed function: ScaleSlider
        function ScaleSliderValueChanged(app, event)
            app.set_pushed = false;
            app.initialize_pushed = false;
            app.scale_changed = true;
        end

        % Value changed function: SaveDataCheckBox
        function SaveDataCheckBoxValueChanged(app, event)
            app.data_saved = app.SaveDataCheckBox.Value;
        end

        % Selection changed function: GraphsButtonGroup
        function GraphsButtonGroupSelectionChanged(app, event)
            app.set_pushed = false;
            app.initialize_pushed = false;
        end

        % Value changed function: DirectionSlider
        function DirectionSliderValueChanged(app, event)
            app.set_pushed = false;
            app.initialize_pushed = false;
            app.dir_changed = true;
        end

        % Selection changed function: PlantsButtonGroup
        function PlantsButtonGroupSelectionChanged(app, event)
            app.set_pushed = false;
            app.initialize_pushed = false;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MascotUIFigure = uifigure;
            app.MascotUIFigure.Name = 'Multi-Agent System Control and Optimization Tester 1.0';
            app.TabGroup = uitabgroup(app.MascotUIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.InitializationPanel = uipanel(app.SimulationTab);
            app.InitializationPanel.Title = 'Initialization';
            app.RobotNumberDropDownLabel = uilabel(app.InitializationPanel);
            app.RobotNumberDropDownLabel.Text = 'Robot Number';
            app.RobotNumberDropDown = uidropdown(app.InitializationPanel);
            app.RobotNumberDropDown.Items = {'5', '6', '7', '8'};
            app.RobotNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotNumberDropDownValueChanged, true);
            app.RobotNumberDropDown.Value = '5';
            app.InitializeButton = uibutton(app.InitializationPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.SaveButton = uibutton(app.InitializationPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UIAxes = uiaxes(app.SimulationTab);
            title(app.UIAxes, 'Simulation Scene')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.OptionsPanel = uipanel(app.SimulationTab);
            app.OptionsPanel.Title = 'Options';
            app.SaveDataCheckBox = uicheckbox(app.OptionsPanel);
            app.SaveDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDataCheckBoxValueChanged, true);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.UseBuiltinAxisCheckBox = uicheckbox(app.OptionsPanel);
            app.UseBuiltinAxisCheckBox.Text = 'Use Built-in Axis';
            app.UseBuiltinAxisCheckBox.Value = true;
            app.SpeedEditFieldLabel = uilabel(app.OptionsPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.SpeedEditField.Limits = [1 10];
            app.SpeedEditField.RoundFractionalValues = 'on';
            app.SpeedEditField.Value = 4;
            app.dtEditFieldLabel = uilabel(app.OptionsPanel);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.dtEditField.Limits = [0.001 0.1];
            app.dtEditField.Value = 0.05;
            app.StartButton = uibutton(app.OptionsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.OptionsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ClearButton = uibutton(app.OptionsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ResetButton = uibutton(app.OptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ScaleSliderLabel = uilabel(app.OptionsPanel);
            app.ScaleSliderLabel.Text = 'Scale';
            app.ScaleSlider = uislider(app.OptionsPanel);
            app.ScaleSlider.Limits = [0.5 1];
            app.ScaleSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleSliderValueChanged, true);
            app.ScaleSlider.Value = 0.8;
            app.DirectionSliderLabel = uilabel(app.OptionsPanel);
            app.DirectionSliderLabel.Text = 'Direction';
            app.DirectionSlider = uislider(app.OptionsPanel);
            app.DirectionSlider.Limits = [-3 3];
            app.DirectionSlider.ValueChangedFcn = createCallbackFcn(app, @DirectionSliderValueChanged, true);
            app.StateButtonGroup = uibuttongroup(app.SimulationTab);
            app.StateButtonGroup.Title = 'State';
            app.SimulateButton = uitogglebutton(app.StateButtonGroup);
            app.SimulateButton.Text = 'Simulate';
            app.SimulateButton.Value = true;
            app.CompareButton = uitogglebutton(app.StateButtonGroup);
            app.CompareButton.Text = 'Compare';
            app.Log = uitextarea(app.SimulationTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ExperimentsButtonGroup = uibuttongroup(app.SettingsTab);
            app.ExperimentsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExperimentsButtonGroupSelectionChanged, true);
            app.ExperimentsButtonGroup.Title = 'Experiments';
            app.TrajectoryTrackingButton = uiradiobutton(app.ExperimentsButtonGroup);
            app.TrajectoryTrackingButton.Text = 'Trajectory Tracking';
            app.TrajectoryTrackingButton.Value = true;
            app.CollisionAvoidanceButton = uiradiobutton(app.ExperimentsButtonGroup);
            app.CollisionAvoidanceButton.Text = 'Collision Avoidance';
            app.FormationManeuverButton = uiradiobutton(app.ExperimentsButtonGroup);
            app.FormationManeuverButton.Text = 'Formation Maneuver';
            app.GraphsButtonGroup = uibuttongroup(app.SettingsTab);
            app.GraphsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GraphsButtonGroupSelectionChanged, true);
            app.GraphsButtonGroup.Title = 'Graphs';
            app.LaplacianButton = uiradiobutton(app.GraphsButtonGroup);
            app.LaplacianButton.Text = 'Laplacian';
            app.LaplacianButton.Value = true;
            app.StressmatrixButton = uiradiobutton(app.GraphsButtonGroup);
            app.StressmatrixButton.Text = 'Stress matrix';
            app.PlantsButtonGroup = uibuttongroup(app.SettingsTab);
            app.PlantsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlantsButtonGroupSelectionChanged, true);
            app.PlantsButtonGroup.Title = 'Plants';
            app.IntegratorButton = uiradiobutton(app.PlantsButtonGroup);
            app.IntegratorButton.Text = 'Integrator';
            app.IntegratorButton.Value = true;
            app.ManipulatorButton = uiradiobutton(app.PlantsButtonGroup);
            app.ManipulatorButton.Text = 'Manipulator';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Mascot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MascotUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MascotUIFigure)
        end
    end
end