classdef Mascot_old < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MascotUIFigure                  matlab.ui.Figure
        ScriptMenu                      matlab.ui.container.Menu
        PlannerMenu                     matlab.ui.container.Menu
        polytopeSearchMenu              matlab.ui.container.Menu
        ControllerMenu                  matlab.ui.container.Menu
        integratedCooperativeControlMenu  matlab.ui.container.Menu
        nullspaceBehavioralControlMenu  matlab.ui.container.Menu
        OptionMenu                      matlab.ui.container.Menu
        ConfigMenu                      matlab.ui.container.Menu
        ReplayMenu                      matlab.ui.container.Menu
        ResultMenu                      matlab.ui.container.Menu
        InitializationPanel             matlab.ui.container.Panel
        NumDropDownLabel                matlab.ui.control.Label
        NumDropDown                     matlab.ui.control.DropDown
        InitButton                      matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        ScaleEditFieldLabel             matlab.ui.control.Label
        ScaleEditField                  matlab.ui.control.NumericEditField
        PlanButton                      matlab.ui.control.Button
        ShowButton                      matlab.ui.control.Button
        SimulationPanel                 matlab.ui.container.Panel
        dtEditFieldLabel                matlab.ui.control.Label
        dtEditField                     matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        DimDropDownLabel                matlab.ui.control.Label
        DimDropDown                     matlab.ui.control.DropDown
        SpeedDropDownLabel              matlab.ui.control.Label
        SpeedDropDown                   matlab.ui.control.DropDown
        TEditFieldLabel                 matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        Log                             matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        % bool
        is_checked
        is_pushed
        % path
        current_path
        % plan
        trajectory
        polytope
        % scene
        scene_handle
        uiaxis
        % simu
        data
        % script
        planner
        controller
    end
    
    methods (Access = private)
        
        function sceneInitialize(app,robot_num,link_scale)
            % load settings
            par_load = {'joint_ref_func','robot_desired','cone','envir','disp_end_siz','state_lim'};
            load([app.current_path '/data/settings'],par_load{:});
            % calculate parameters
            [vert_ref,angle_inward,angle_outward] = Mascot.vertRef(robot_num,state_lim.vert_affine);
            [safe_con,disp_base_mat_func] = Mascot.safetyConstraint(robot_num,robot_desired,cone,state_lim,disp_end_siz);
            vert_dim = size(vert_ref,2);
            link_num = length(robot_desired.link);
            % graph
            for i=1:robot_num
                disp_base_ref(i,:) = element(disp_base_mat_func(i,0,link_scale),{1:vert_dim,vert_dim+1})';
            end
            graph_base = Mascot.undirectedGraph(robot_num,disp_base_ref);
            % planar revolute used for simulation
            robot_simu = PlanarRevolute.copy(robot_desired,robot_num,'robot_simu');
            % check base collision
            check_collide = true;
            while check_collide
                % set functions
                pos_end_shift = rand([robot_num,2])*2*state_lim.pos_end-state_lim.pos_end;
                % initialization
                for i=1:robot_num
                    % initial optimization variables
                    opt_state(i,:) = randlim(safe_con.lim_func(i));
                    joint_ref(i,:) = joint_ref_func(angle_inward(i),link_scale);
                    opt_state(i,:)= convproj(opt_state(i,:),...
                        safe_con.A_func(i,link_scale,joint_ref(i,:)),...
                        safe_con.b_func(i,joint_ref(i,:)),...
                        safe_con.lim_func(i));
                    [disp_end(i,:),coef_base(i,:),joint(i,:)] = ...
                        Mascot.hatSplit(opt_state(i,:),[vert_dim,vert_dim+1,link_num]);
                    % initial end-effector position
                    disp_base_hat(i,:) = disp_base_mat_func(i,0,link_scale)*coef_base(i,:)';
                    pos_end(i,:) = envir.start+disp_end(i,:)+pos_end_shift(i,:);
                    [pos_base(i,:),pos_base_hat(i,:)] =...
                        Mascot.baseSplit(robot_desired,joint(i,:),pos_end(i,:),envir.start+pos_end_shift(i,:),disp_base_hat(i,:));
                end
                % check if initial positions collide with each other
                check_collide = false;
                if ~mod(robot_num,2)
                    check_ind = [1:2:robot_num-1 robot_num:-2:1];
                else
                    check_ind = [2:2:robot_num-1 robot_num:-2:1];
                end
                if norm(pos_base(1,:)-pos_base(robot_num,:))< 2*robot_desired.radius
                    check_collide = true;
                end
                for i=1:length(check_ind)-1
                    if norm(pos_base(check_ind(i),:)-pos_base(check_ind(i+1),:))< 2*robot_desired.radius
                        check_collide = true;
                    end
                end
            end
            % trajectory
            traj = app.trajectory;
            region = app.polytope;
            % initialize trajectory
            pos_traj_ref = traj.pos(1,:);
            pos_traj_hat = [pos_end-disp_end,zeros(robot_num,1)];
            % optimal scale
            opt_scale = ones(robot_num,1)*link_scale;
            % distance between end_hat and base
            for i=1:robot_num
                dist_hat(i,:) = norm(pos_end(i,:)-pos_base_hat(i,:))+0.5;
            end
            % save initials
            var_save = {'pos_traj_ref','pos_traj_hat','joint','pos_end','pos_base',...
                'pos_base_hat','disp_end','coef_base','opt_scale','robot_num','robot_simu',...
                'traj','region','graph_base','angle_inward','angle_outward',...
                'safe_con','disp_base_mat_func','vert_ref','joint_ref','link_scale'};
            save([app.current_path '/data/initials'],var_save{:});
        end
        
        function [is_loop_broken, data] = sceneSimulate(app)
            % load settings
            par_load = {'robot_desired','default'};
            load([app.current_path '/data/settings'],par_load{:});
            % load initials
            var_load = {'pos_traj_ref','pos_traj_hat','pos_end','disp_end','coef_base',...
                'joint','opt_scale','robot_num','traj','region','disp_base_mat_func'};
            load([app.current_path '/data/initials'],var_load{:});
            % zero velocities
            dot_pos_traj_hat = zeros(size(pos_traj_hat));
            dot_pos_end = zeros(size(pos_end));
            dot_joint = zeros(size(joint));
            dot_coef_base = zeros(size(coef_base));
            dot_disp_end = zeros(size(disp_end));
            dot_opt_scale = zeros(size(opt_scale));
            % lagrangian
            lambda = [];
            dot_lambda = [];
            % simulation loop
            loop = 0;
            is_loop_broken = false;
            t = traj.time(1);
            final_time = app.TEditField.Value;
            while t <= min(final_time,traj.time(end)) % you can end it in advance
                loop = loop+1;
                % change simulation options
                playspeed = str2double(app.SpeedDropDown.Value);
                interval = app.dtEditField.Value;
                final_time = app.TEditField.Value;
                % get reference velocity
                if t<=traj.time(end)
                    dot_pos_traj_ref = interp1(traj.time,traj.dot_pos,t);
                else
                    dot_pos_traj_ref = zeros(size(pos_traj_ref));
                end
                % robot controller
                ctrl_argin = {pos_traj_ref,pos_traj_hat,pos_end,disp_end,coef_base,joint,...
                    opt_scale,lambda,dot_pos_traj_ref,dot_pos_traj_hat,dot_pos_end,dot_disp_end,...
                    dot_coef_base,dot_joint,dot_opt_scale,dot_lambda,t,app.current_path};
                if isempty(app.controller)
                    app.controller = default.controller;
                end
                eval(['import mascot.' app.controller]);
                eval(['[dot_pos_traj_hat,dot_pos_end,dot_disp_end,dot_coef_base,dot_joint,dot_opt_scale,dot_lambda,lambda,result] = '...
                    app.controller '(ctrl_argin{:});']);
                % update states
                pos_traj_ref = pos_traj_ref + dot_pos_traj_ref*interval;
                pos_traj_hat = pos_traj_hat + dot_pos_traj_hat*interval;
                pos_end = pos_end + dot_pos_end*interval;
                disp_end = disp_end + dot_disp_end*interval;
                coef_base = coef_base + dot_coef_base*interval;
                joint = joint + dot_joint*interval;
                opt_scale = opt_scale + dot_opt_scale*interval;
                lambda = lambda+dot_lambda*interval;
                % update figure
                if mod(loop,playspeed)==0
                    app.scene_handle = Mascot.sceneAnimate(app.scene_handle,pos_traj_ref,pos_traj_hat,pos_end,joint,coef_base,opt_scale,app.current_path);
                    drawnow
                end
                % show result
                app.Log.Value = [{['Controller: ' app.controller]},{['time = ' num2str(t) 's']},result];
                % restore data
                data(loop).pos_traj_ref = pos_traj_ref;
                data(loop).pos_traj_hat = pos_traj_hat;
                data(loop).pos_end = pos_end;
                data(loop).disp_end = disp_end;
                data(loop).coef_base = coef_base;
                data(loop).joint = joint;
                data(loop).opt_scale = opt_scale;
                data(loop).lambda = lambda;
                data(loop).time = t;
                % next loop
                t = t+interval;
                % pause
                if app.is_pushed.stop
                    app.Log.Value = 'Simulation stopped.';
                    uiwait(msgbox('Press OK to continue.'));
                    app.is_pushed.stop = false;
                end
                % clear
                if app.is_pushed.clear
                    is_loop_broken = true;
                    break
                end
                % data to mat per loop (avoid collapse)
                save([app.current_path '/data/simudata'],'data');
            end
        end
        
        
    end
    
    methods (Static)
        
        function [vert_ref,angle_inward,angle_outward] = vertRef(robot_num,vert_affine)
            % polytope
            angle_central = 2*pi/robot_num;
            if ~mod(robot_num,2)
                vert_ref = [cos([0 kron(1:(robot_num-1)/2,[1,-1]) robot_num/2]*angle_central)',...
                    sin([0 kron(1:(robot_num-1)/2,[1,-1]) robot_num/2]*angle_central)'];
            else
                vert_ref = [cos([0 kron(1:(robot_num-1)/2,[1,-1])]*angle_central)',...
                    sin([0 kron(1:(robot_num-1)/2,[1,-1])]*angle_central)'];
            end
            vert_ref = vert_ref*vert_affine';
            angle_inward = cart2pol(-vert_ref(:,1),-vert_ref(:,2));
            angle_outward = cart2pol(vert_ref(:,1),vert_ref(:,2));
        end
        
        function [joint_ref_func,robot_desired] = jointRef(radius,altitude,height,link_list,angle_safety)
            % load settings
            link_num = length(link_list);
            % desired joint posture
            joint_sign = -(-1).^(1:link_num-1);
            joint_Ath = [tril(ones(link_num));-tril(ones(link_num))];
            joint_bth = kron([angle_safety/2;angle_safety/2],ones(link_num,1));
            joint_init = [-angle_safety/2,joint_sign*angle_safety];
            %             joint_bth = kron([pi+angle_safety/2;pi-angle_safety/2],ones(link_num,1));
            %             joint_init = [pi-angle_safety/2,joint_sign*angle_safety];
            joint_lim_desired = [-angle_safety,(joint_sign-1)/2*angle_safety;
                0,(joint_sign+1)/2*angle_safety];
            robot_desired = PlanarRevolute(link_list,'name','robot_desired',...
                'height',height,'radius',radius,'altitude',...
                altitude,'qlim',joint_lim_desired,'Aq',joint_Ath,...
                'bq',joint_bth,'q0',joint_init);
            joint_desired_ref_func = @(scale) robot_desired.ikine2d([0,0],sum(link_list)*scale*[-1,0,0]);
            % scale: (0,1), angle: [-pi,pi]
            joint_ref_func = @(angle,scale) joint_desired_ref_func(scale)+[angle,zeros(1,link_num-1)];
        end
        
        function cone = coneRef(angle_safety)
            % edge vectors
            cone.angle_min_func = @(angle) angle-angle_safety/2;
            cone.angle_max_func = @(angle) angle+angle_safety/2;
            cone.vec_min_func = @(angle) [cos(angle-angle_safety/2);sin(angle-angle_safety/2)];
            cone.vec_max_func = @(angle) [cos(angle+angle_safety/2);sin(angle+angle_safety/2)];
            cone.affine_func = @(angle) [skew_(cone.vec_min_func(angle));-skew_(cone.vec_max_func(angle))];
        end
        
        function [safe_con,disp_base_mat_func,disp_end_ref] = safetyConstraint(robot_num,robot_desired,cone,state_lim,disp_end_siz)
            % load settings
            [vert_ref,angle_inward,~] = Mascot.vertRef(robot_num,state_lim.vert_affine);
            link_list = robot_desired.link;
            disp_end_ref = vert_ref*disp_end_siz;
            disp_base_max = disp_end_ref+sum(link_list)*vert_ref./normby(vert_ref,1);
            disp_base_mat_func = @(i,angle,scale) [eye(2),rot2(angle)*(scale*(disp_base_max(i,:)-disp_end_ref(i,:))'+disp_end_ref(i,:)')];
            link_num = length(robot_desired.link);
            % limits
            coef_base_lim = [-state_lim.disp_base,-state_lim.disp_base,1-state_lim.siz_base;
                state_lim.disp_base,state_lim.disp_base,1+state_lim.siz_base];
            for i=1:robot_num
                disp_end_lim(:,:,i) = disp_end_ref(i,:)+[-state_lim.disp_end;state_lim.disp_end];
                joint_lim(:,:,i) = robot_desired.qlim+[angle_inward(i),zeros(1,link_num-1)];
            end
            % inequality
            fkine_func = @(joint) robot_desired.getFkine(robot_desired.link,joint);
            jacob_func = @(joint) robot_desired.getJacob(robot_desired.link,joint);
            safe_Aco_func = @(i,scale,joint) -cone.affine_func(angle_inward(i))*[eye(2),-disp_base_mat_func(i,0,scale),-jacob_func(joint)];
            safe_bco_func = @(i,joint) -cone.affine_func(angle_inward(i))*(fkine_func(joint)'-jacob_func(joint)*joint');
            safe_Ath = [zeros(link_num*2,size(disp_end_lim,2)+size(coef_base_lim,2)),robot_desired.Aq];
            safe_bth = @(i) kron([cone.angle_max_func(angle_inward(i));-cone.angle_min_func(angle_inward(i))],ones(link_num,1));
            % all in A, b, lim
            safe_con.A_func = @(i,scale,joint) [safe_Aco_func(i,scale,joint);safe_Ath];
            safe_con.b_func = @(i,joint) [safe_bco_func(i,joint);safe_bth(i)];
            safe_con.lim_func = @(i) [disp_end_lim(:,:,i),coef_base_lim,joint_lim(:,:,i)];
        end
        
        function varargout = hatSplit(hat_state,list_split)
            % Split state
            list_split = [0,list_split];
            for i=1:length(list_split)-1
                varargout{i} = hat_state(:,sum(list_split(1:i))+1:sum(list_split(1:i+1)),:);
            end
        end
        
        function [pos_base,pos_base_hat] = baseSplit(robot_desired,joint,pos_end,pos_traj_hat,disp_base_hat)
            fkine_func = @(joint) robot_desired.getFkine(robot_desired.link,joint);
            pos_base = pos_end-fkine_func(joint);
            pos_base_hat = pos_traj_hat+disp_base_hat;
        end
        
        %         function [disp_end,coef_base,joint,pos_base,pos_base_hat] = optStateSplit(robot_desired,opt_state,pos_end,pos_traj_hat,disp_base_ref)
        %             [disp_end,coef_base,joint] = Mascot.hatSplit(opt_state,[size(pos_end,2),size(pos_end,2)+1,length(robot_desired.link)]);
        %             [pos_base,pos_base_hat] = Mascot.baseSplit(robot_desired,joint,pos_end,pos_traj_hat,coef_base*disp_base_ref');
        %         end
        
        function graph = undirectedGraph(robot_num,vert_ref)
            % undirected graph for at most 8 agents
            graph = bigraph(robot_num,vert_ref);
        end
        
        function [scene, uiaxis] = trajPlot(traj, region, current_path, dim)
            close all
            par_plot = {'robot_desired','envir','wall_num'};
            load([current_path '/data/settings'],par_plot{:})
            % create axis
            scene_lim = vec([envir.lb;envir.ub])';
            if dim==3
                scene_lim = [scene_lim 0 max(diff(scene_lim))];
            end
            object_height = robot_desired.altitude+robot_desired.height;
            plat_height = robot_desired.altitude;
            uiaxis = gca; hold on; axis(scene_lim);
            set(gcf,'Color',[1,1,1]);
            % start and goal
            scene.start = plot(uiaxis, envir.start(:,1), envir.start(:,2),'bs','LineWidth',2);
            scene.goal = plot(uiaxis, envir.goal(:,1), envir.goal(:,2),'b^','LineWidth',2);
            % reference trajectory, obstacles and barrier
            scene.trajectory = plot(uiaxis, traj.pos(:,1),traj.pos(:,2),'g','LineWidth',2);
            if isfield(envir,'obstacle')
                % Draw obstacle interiors
                for j = 1:length(envir.obstacle)
                    obs = envir.obstacle{j}';
                    if size(obs, 2) > 1
                        if size(obs, 2) > 2
                            k = convhull_(obs');
                        else
                            k = [1,2,1];
                        end
                        xobs = kron(obs(1,k),[1;1]);
                        yobs = kron(obs(2,k),[1;1]);
                        zobs = kron(ones(1,length(k)),[0;1]*(plat_height+rand*(object_height-plat_height)*(j>wall_num)));
                        [fobs,vobs]= surf2patch(xobs,yobs,zobs);
                        scene.obstacle(j).interior(1) = patch(uiaxis, 'vertices',vobs,'faces',fobs,...
                            'EdgeColor', 'k', 'FaceColor', [.6,.6,.6], 'LineWidth', 1);
                        scene.obstacle(j).interior(2) = patch(uiaxis, xobs',yobs',zobs',...
                            'k', 'FaceColor', [.6,.6,.6], 'LineWidth', 1);
                    else
                        scene.obstacle(j).interior = plot(uiaxis, obs(1,:), obs(2,:), 'k', 'LineWidth', 1);
                    end
                end
                % Draw obstacle dilated boundaries
                for j = 1:length(envir.obstacle)
                    obs = dilate(envir.obstacle{j},robot_desired.radius)';
                    if size(obs, 2) > 1
                        if size(obs, 2) > 2
                            k = convhull_(obs');
                        else
                            k = [1,2,1];
                        end
                        scene.obstacle(j).dilate = plot(uiaxis, obs(1,k), obs(2,k), 'Color', [.6,.6,.6], 'LineWidth', 2);
                    end
                end
            end
            % Draw boundaries
            scene.boundary = plot(uiaxis, [envir.lb(1),envir.ub(1),envir.ub(1),envir.lb(1),envir.lb(1)],...
                [envir.lb(2),envir.lb(2),envir.ub(2),envir.ub(2),envir.lb(2)], 'k-');
            for i=1:length(region)
                if iscell(region)
                    regionstruct = [region{:}];
                else
                    regionstruct = region;
                end
                A = regionstruct(i).A;
                b = regionstruct(i).b;
                if ~isempty(A)
                    V = lcon2vert(A, b);
                    k = convhull(V(:,1), V(:,2));
                    scene.region(i) = plot(uiaxis, V(k,1), V(k,2), 'ro-', 'LineWidth', 2);
                end
            end
        end
        
        function [scene, uiaxis] = scenePlot(pos_traj_ref,pos_traj_hat,pos_end,joint,coef_base,opt_scale,current_path,dim,is_plotted)
            close all
            % load settings
            if nargin<9
                par_plot = {'is_plotted','cone','envir','robot_desired','wall_num'};
            else
                par_plot = {'cone','envir','robot_desired','wall_num'};
            end
            load([current_path '/data/settings'],par_plot{:})
            % load initials
            var_load = {'robot_num','disp_base_mat_func','robot_simu','traj','region','angle_inward','angle_outward'};
            load([current_path '/data/initials'],var_load{:});
            % state split
            for i=1:size(pos_end,1)
                [pos_base(i,:),pos_base_hat(i,:)] = Mascot.baseSplit(robot_desired,...
                    joint(i,:),pos_end(i,:),pos_traj_hat(i,1:2),coef_base(i,:)*disp_base_mat_func(i,0,opt_scale(i))');
            end
            % create axis
            scene_lim = vec([envir.lb;envir.ub])';
            if dim==3
                scene_lim = [scene_lim 0 max(diff(scene_lim))];
            end
            object_height = robot_desired.altitude+robot_desired.height;
            plat_height = robot_desired.altitude;
            object_width = 0.3*plat_height;
            uiaxis = gca; hold on; axis(scene_lim);
            set(gcf,'Color',[1,1,1]);
            % robots
            for i=1:robot_num
                if is_plotted.robot
                    scene.robot{i} = robot_simu(i).plot(uiaxis, joint(i,:), q(SE2(pos_base(i,:))),...
                        'workspace', scene_lim, 'dim', length(scene_lim)/2);
                end
                if is_plotted.label
                    scene.label(i) = text(uiaxis, pos_base(i,1),pos_base(i,2),object_height,...
                        num2str(i),'HorizontalAlignment','center','FontSize',400/max(diff(scene_lim)));
                end
            end
            % reference trajectory, obstacles and barrier
            if is_plotted.trajectory
                scene.trajectory = plot(uiaxis, traj.pos(:,1),traj.pos(:,2),'g','LineWidth',2);
            end
            if is_plotted.obstacle
                if isfield(envir,'obstacle')
                    % Draw obstacle interiors
                    for j = 1:length(envir.obstacle)
                        obs = envir.obstacle{j}';
                        if size(obs, 2) > 1
                            if size(obs, 2) > 2
                                k = convhull_(obs');
                            else
                                k = [1,2,1];
                            end
                            xobs = kron(obs(1,k),[1;1]);
                            yobs = kron(obs(2,k),[1;1]);
                            zobs = kron(ones(1,length(k)),[0;1]*(plat_height+rand*(object_height-plat_height)*(j>wall_num)));
                            [fobs,vobs]= surf2patch(xobs,yobs,zobs);
                            scene.obstacle(j).interior(1) = patch(uiaxis, 'vertices',vobs,'faces',fobs,...
                                'EdgeColor', 'k', 'FaceColor', [.6,.6,.6], 'LineWidth', 1);
                            scene.obstacle(j).interior(2) = patch(uiaxis, xobs',yobs',zobs',...
                                'k', 'FaceColor', [.6,.6,.6], 'LineWidth', 1);
                        else
                            scene.obstacle(j).interior = plot(uiaxis, obs(1,:), obs(2,:), 'k', 'LineWidth', 1);
                        end
                    end
                    % Draw obstacle dilated boundaries
                    for j = 1:length(envir.obstacle)
                        obs = dilate(envir.obstacle{j},robot_desired.radius)';
                        if size(obs, 2) > 1
                            if size(obs, 2) > 2
                                k = convhull_(obs');
                            else
                                k = [1,2,1];
                            end
                            scene.obstacle(j).dilate = plot(uiaxis, obs(1,k), obs(2,k), 'Color', [.6,.6,.6], 'LineWidth', 2);
                        end
                    end
                end
                % Draw boundaries
                scene.boundary = plot(uiaxis, [envir.lb(1),envir.ub(1),envir.ub(1),envir.lb(1),envir.lb(1)],...
                    [envir.lb(2),envir.lb(2),envir.ub(2),envir.ub(2),envir.lb(2)], 'k-');
            end
            if is_plotted.region
                for i=1:length(region)
                    if iscell(region)
                        regionstruct = [region{:}];
                    else
                        regionstruct = region;
                    end
                    A = regionstruct(i).A;
                    b = regionstruct(i).b;
                    if ~isempty(A)
                        V = lcon2vert(A, b);
                        k = convhull(V(:,1), V(:,2));
                        scene.region(i) = plot(uiaxis, V(k,1), V(k,2), 'ro-', 'LineWidth', 2);
                    end
                end
            end
            % initial reference and estimation
            if is_plotted.estimation
                scene.estimation = plot3(uiaxis, pos_traj_hat(:,1),pos_traj_hat(:,2),...
                    ones(size(pos_traj_hat,1),1)*object_height+object_width,'m.','LineWidth',2);
            end
            if is_plotted.reference
                scene.reference = plot3(uiaxis, pos_traj_ref(:,1),pos_traj_ref(:,2),...
                    ones(size(pos_traj_ref,1),1)*object_height+object_width,'mx','LineWidth',2);
            end
            % object
            if is_plotted.object
                pobj = pos_end';
                if size(pobj, 2) > 1
                    if size(pobj, 2) > 2
                        k = convhull_(pobj');
                    else
                        k = [1,2,1];
                    end
                    xobj = kron(pobj(1,k),[1;1]);
                    yobj = kron(pobj(2,k),[1;1]);
                    zobj = kron(ones(1,length(k)),object_height+[-1;1]*object_width);
                    [fobj,vobj]= surf2patch(xobj,yobj,zobj);
                    scene.object.interior(1) = patch(uiaxis, 'vertices',vobj,'faces',fobj,...
                        'EdgeColor', 'k', 'FaceColor', [.55 .55 .55], 'LineWidth', 1);
                    scene.object.interior(2) = patch(uiaxis, xobj',yobj',zobj',...
                        'k', 'FaceColor', [.55 .55 .55], 'LineWidth', 1);
                else
                    scene.object.interior = plot(uiaxis, pobj(1,:), pobj(2,:), 'k', 'LineWidth', 1);
                end
            end
            % base estimation
            if is_plotted.basehat
                scene.basehat = plot3(uiaxis, pos_base_hat(:,1),pos_base_hat(:,2),...
                    ones(size(pos_base_hat,1),1)*object_height*(3-dim),'cs','LineWidth',2);
            end
            % safety constriants
            if is_plotted.cone
                for i=1:robot_num
                    x = [pos_base_hat([i i],1);pos_end([i i],1)];
                    y = [pos_base_hat([i i],2);pos_end([i i],2)];
                    vec_in_min = cone.vec_min_func(angle_inward(i));
                    vec_in_max = cone.vec_max_func(angle_inward(i));
                    vec_out_min = cone.vec_min_func(angle_outward(i));
                    vec_out_max = cone.vec_max_func(angle_outward(i));
                    u = [vec_in_min(1);vec_in_max(1);
                        vec_out_min(1);vec_out_max(1)];
                    v = [vec_in_min(2);vec_in_max(2);
                        vec_out_min(2);vec_out_max(2)];
                    u = sum(robot_simu(i).link)/2*u;
                    v = sum(robot_simu(i).link)/2*v;
                    scene.cone(i) = quiver(uiaxis, x,y,u,v,'linestyle','--','linewidth',1);
                end
            end
            if is_plotted.workrange
                longdis = 1.5;
                for i=1:robot_num
                    vec_in_min = cone.vec_min_func(angle_inward(i));
                    vec_in_max = cone.vec_max_func(angle_inward(i));
                    vec_out_min = cone.vec_min_func(angle_outward(i));
                    vec_out_max = cone.vec_max_func(angle_outward(i));
                    workrange_vts = [pos_base_hat(i,:);pos_base_hat(i,:)+longdis*[vec_in_min,vec_in_max]';
                        pos_end(i,:);pos_end(i,:)+longdis*[vec_out_min,vec_out_max]'];
                    workrange_vts = workrange_vts(convhull_(workrange_vts),:);
                    scene.workrange(i) = patch('Faces',1:size(workrange_vts,1),'Vertices',workrange_vts,...
                        'LineStyle','none','FaceColor','g','FaceAlpha',0.3);
                end
            end
            hold off
            if ~is_plotted.axis
                axis off
            end
        end
        
        function scene = sceneAnimate(scene,pos_traj_ref,pos_traj_hat,pos_end,joint,coef_base,opt_scale,current_path)
            % load settings
            par_plot = {'cone','robot_desired'};
            load([current_path '/data/settings'],par_plot{:})
            % load initials
            var_load = {'robot_num','disp_base_mat_func','robot_simu','traj','angle_inward','angle_outward'};
            load([current_path '/data/initials'],var_load{:});
            % state split
            for i=1:size(pos_end,1)
                [pos_base(i,:),pos_base_hat(i,:)] = Mascot.baseSplit(robot_desired,...
                    joint(i,:),pos_end(i,:),pos_traj_hat(i,1:2),coef_base(i,:)*disp_base_mat_func(i,0,opt_scale(i))');
            end
            object_height = robot_desired.altitude+robot_desired.height;
            plat_height = robot_desired.altitude;
            object_width = 0.3*plat_height;
            % robots
            for i=1:robot_num
                if isfield(scene,'robot')
                    robot_simu(i).animate(joint(i,:), q(SE2(pos_base(i,:))),scene.robot{i}.group);
                end
                if isfield(scene,'label')
                    label_pos = [pos_base(i,1),pos_base(i,2),scene.label(i).Position(3)];
                    set(scene.label(i),'position',label_pos);
                end
            end
            % initial reference and estimation
            if isfield(scene,'reference')
                set(scene.reference,'xdata',pos_traj_ref(:,1),'ydata',pos_traj_ref(:,2));
            end
            if isfield(scene,'estimation')
                set(scene.estimation,'xdata',pos_traj_hat(:,1),'ydata',pos_traj_hat(:,2));
            end
            % object
            if isfield(scene,'object')
                pobj = pos_end';
                if size(pobj, 2) > 1
                    if size(pobj, 2) > 2
                        k = convhull_(pobj');
                    else
                        k = [1,2,1];
                    end
                    xobj = kron(pobj(1,k),[1;1]);
                    yobj = kron(pobj(2,k),[1;1]);
                    zobj = kron(ones(1,length(k)),object_height+[-1;1]*object_width);
                    [fobj,vobj]= surf2patch(xobj,yobj,zobj);
                    set(scene.object.interior(1),'vertices',vobj,'faces',fobj);
                    set(scene.object.interior(2),'xdata',xobj','ydata',yobj','zdata',zobj');
                else
                    set(scene.object.interior,'xdata',pobj(1,:),'ydata',pobj(2,:));
                end
            end
            % base estimation
            if isfield(scene,'basehat')
                set(scene.basehat,'xdata',pos_base_hat(:,1),'ydata',pos_base_hat(:,2));
            end
            % safety constriants
            if isfield(scene,'cone')
                for i=1:robot_num
                    x = [pos_base_hat([i i],1);pos_end([i i],1)];
                    y = [pos_base_hat([i i],2);pos_end([i i],2)];
                    vec_in_min = cone.vec_min_func(angle_inward(i));
                    vec_in_max = cone.vec_max_func(angle_inward(i));
                    vec_out_min = cone.vec_min_func(angle_outward(i));
                    vec_out_max = cone.vec_max_func(angle_outward(i));
                    u = [vec_in_min(1);vec_in_max(1);
                        vec_out_min(1);vec_out_max(1)];
                    v = [vec_in_min(2);vec_in_max(2);
                        vec_out_min(2);vec_out_max(2)];
                    u = sum(robot_simu(i).link)/2*u;
                    v = sum(robot_simu(i).link)/2*v;
                    set(scene.cone(i),'xdata',x,'ydata',y,'udata',u,'vdata',v);
                end
            end
            if isfield(scene,'workrange')
                longdis = 1.5;
                for i=1:robot_num
                    vec_in_min = cone.vec_min_func(angle_inward(i));
                    vec_in_max = cone.vec_max_func(angle_inward(i));
                    vec_out_min = cone.vec_min_func(angle_outward(i));
                    vec_out_max = cone.vec_max_func(angle_outward(i));
                    workrange_vts = [pos_base_hat(i,:);pos_base_hat(i,:)+longdis*[vec_in_min,vec_in_max]';
                        pos_end(i,:);pos_end(i,:)+longdis*[vec_out_min,vec_out_max]'];
                    workrange_vts = workrange_vts(convhull_(workrange_vts),:);
                    set(scene.workrange(i),'Faces',1:size(workrange_vts,1),'Vertices',workrange_vts);
                end
            end
            % circle constraints
            if isfield(scene,'circle')
                for i=1:robot_num
                    xcirc = circle_(pos_end(i,:),dist_hat(i));
                    set(scene.circle(i),'xdata',xcirc(:,1),'ydata',xcirc(:,2));
                end
            end
        end
        
        function hreg = regionPlot(A,b)
            if ~isempty(A)
                V = lcon2vert(A, b);
                k = convhull(V(:,1), V(:,2));
                hreg = plot(V(k,1), V(k,2), 'ro-', 'LineWidth', 2);
            end
        end
        
        function vt = wallVert(pt1,pt2,width,dir)
            pt1 = pt1(:)';
            pt2 = pt2(:)';
            vt = [pt1+[1,1]/2.*[-1;1].*dir*width;pt2+[1,1]/2.*[-1;1].*dir*width];
            vt = vt(convhull_(vt),:);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            % change to current folder
            app.current_path = fileparts(mfilename('fullpath'));
            cd(app.current_path);
            app.Log.Value = ['Current Folder: ' app.current_path];
            % init config
            app.ConfigMenuSelected()
            % button states initialization
            app.is_pushed.plan = false;
            app.is_pushed.plot = false;
            app.is_pushed.reset = false;
            app.is_pushed.stop = false;
            app.is_pushed.clear = false;
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            app.Log.Value = 'Initialize environment...';
            % check settings
            if ~exist([app.current_path '/data/settings.mat'],'file')
                app.Log.Value = 'Please set parameters.';
            elseif app.is_pushed.plan
                robot_num = str2double(app.NumDropDown.Value);
                link_scale = app.ScaleEditField.Value;
                app.sceneInitialize(robot_num,link_scale);
                app.Log.Value = 'Initialization finished.';
            else
                app.Log.Value = 'Please plan a trajectory.';
            end
            app.is_pushed.plot = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Log.Value = 'Simulate...';
            % check initials
            if ~app.is_pushed.plot
                app.Log.Value = 'Please plot environment.';
            else
                app.is_pushed.stop = false;
                app.is_pushed.clear = false;
                [is_loop_broken, app.data] = app.sceneSimulate();
                if ~is_loop_broken
                    app.Log.Value = 'Simulation finished.';
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            load([app.current_path '/data/settings.mat'],'envir');
            scene_lim = vec([envir.lb;envir.ub])';
            % There are warnings when dim 3 changes to dim 2
            % because rotate3d cannot be disabled
            if app.DimDropDown=='3'
                scene_lim = [scene_lim 0 max(diff(scene_lim))];
            end
            if ~isempty(app.uiaxis)
                axis(app.uiaxis,scene_lim);
                app.Log.Value = 'Axes reset.';
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if ~isempty(app.uiaxis)&&app.is_pushed.plot
                cla(app.uiaxis);
                app.Log.Value = 'Axes cleared.';
            end
            app.is_pushed.clear = true;
            app.is_pushed.plot = false;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.is_pushed.stop = true;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.Log.Value = 'Create scene...';
            % check initials
            if ~exist([app.current_path '/data/initials.mat'],'file')
                app.Log.Value = 'Please initialize environment.';
            else
                % load initials
                var_load = {'pos_traj_ref','pos_traj_hat','pos_end','joint','coef_base','opt_scale'};
                load([app.current_path '/data/initials'],var_load{:});
                % initialize simulation
                dim = str2double(app.DimDropDown.Value);
                [app.scene_handle,app.uiaxis] = Mascot.scenePlot(pos_traj_ref,pos_traj_hat,pos_end,joint,coef_base,opt_scale,app.current_path,dim);
                app.Log.Value = 'Scene created.';
                app.is_pushed.plot = true;
            end
        end

        % Button pushed function: PlanButton
        function PlanButtonPushed(app, event)
            app.Log.Value = 'Plan trajectory...';
            % check settings
            if ~exist([app.current_path '/data/settings.mat'],'file')
                app.Log.Value = 'Please set parameters.';
            else
                robot_num = str2double(app.NumDropDown.Value);
                link_scale = app.ScaleEditField.Value;
                % load settings
                par_load = {'robot_desired','cone','envir','disp_end_siz','state_lim'};
                load([app.current_path '/data/settings'],par_load{:});
                % calculate parameters
                [~,disp_base_mat_func,disp_end_ref] = Mascot.safetyConstraint(robot_num,robot_desired,cone,state_lim,disp_end_siz);
                vert_dim = size(disp_end_ref,2);
                for i=1:robot_num
                    disp_base_ref(i,:) = element(disp_base_mat_func(i,0,link_scale),{1:vert_dim,vert_dim+1})';
                end
                % trajectory planning
                if isempty(app.planner)
                    load([app.current_path '/data/settings'],'default');
                    app.planner = default.planner;
                end
                app.Log.Value = ['Planner: ' app.planner];
                eval(['import mascot.' app.planner])
                eval(['[app.trajectory,app.polytope] = ' app.planner '(envir,robot_desired,robot_num,link_scale,disp_base_ref,disp_end_ref);']);
                app.Log.Value = 'Plan finished.';
                app.is_pushed.plan = true;
            end
        end

        % Menu selected function: ConfigMenu
        function ConfigMenuSelected(app, event)
            % Config
            run('./option/Config.m')
        end

        % Menu selected function: ReplayMenu
        function ReplayMenuSelected(app, event)
            % Replay
            run('./option/Replay.m')
        end

        % Menu selected function: ResultMenu
        function ResultMenuSelected(app, event)
            % Result
            run('./option/Result.m')
        end

        % Menu selected function: polytopeSearchMenu
        function polytopeSearchMenuSelected(app, event)
            app.planner = app.polytopeSearchMenu.Text;
        end

        % Menu selected function: integratedCooperativeControlMenu
        function integratedCooperativeControlMenuSelected(app, event)
            app.controller = app.integratedCooperativeControlMenu.Text;
        end

        % Menu selected function: nullspaceBehavioralControlMenu
        function nullspaceBehavioralControlMenuSelected(app, event)
            app.controller = app.nullspaceBehavioralControlMenu.Text;
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            % display
            if isempty(app.trajectory)
                % load initials
                var_load = {'traj','region'};
                plan = load([app.current_path '/data/initials'],var_load{:});
                Mascot.trajPlot(plan.traj,plan.region,app.current_path,2);
                app.Log.Value = 'Show old planning trajectory.';
            else
                Mascot.trajPlot(app.trajectory,app.polytope,app.current_path,2);
                app.Log.Value = 'Show current planning trajectory.';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MascotUIFigure = uifigure;
            app.MascotUIFigure.Name = 'Mascot 1.5';
            app.ScriptMenu = uimenu(app.MascotUIFigure);
            app.ScriptMenu.Text = 'Script';
            app.PlannerMenu = uimenu(app.ScriptMenu);
            app.PlannerMenu.Text = 'Planner';
            app.polytopeSearchMenu = uimenu(app.PlannerMenu);
            app.polytopeSearchMenu.MenuSelectedFcn = createCallbackFcn(app, @polytopeSearchMenuSelected, true);
            app.polytopeSearchMenu.Text = 'polytopeSearch';
            app.ControllerMenu = uimenu(app.ScriptMenu);
            app.ControllerMenu.Text = 'Controller';
            app.integratedCooperativeControlMenu = uimenu(app.ControllerMenu);
            app.integratedCooperativeControlMenu.MenuSelectedFcn = createCallbackFcn(app, @integratedCooperativeControlMenuSelected, true);
            app.integratedCooperativeControlMenu.Text = 'integratedCooperativeControl';
            app.nullspaceBehavioralControlMenu = uimenu(app.ControllerMenu);
            app.nullspaceBehavioralControlMenu.MenuSelectedFcn = createCallbackFcn(app, @nullspaceBehavioralControlMenuSelected, true);
            app.nullspaceBehavioralControlMenu.Text = 'nullspaceBehavioralControl';
            app.OptionMenu = uimenu(app.MascotUIFigure);
            app.OptionMenu.Text = 'Option';
            app.ConfigMenu = uimenu(app.OptionMenu);
            app.ConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigMenuSelected, true);
            app.ConfigMenu.Text = 'Config';
            app.ReplayMenu = uimenu(app.OptionMenu);
            app.ReplayMenu.MenuSelectedFcn = createCallbackFcn(app, @ReplayMenuSelected, true);
            app.ReplayMenu.Text = 'Replay';
            app.ResultMenu = uimenu(app.OptionMenu);
            app.ResultMenu.MenuSelectedFcn = createCallbackFcn(app, @ResultMenuSelected, true);
            app.ResultMenu.Text = 'Result';
            app.InitializationPanel = uipanel(app.MascotUIFigure);
            app.InitializationPanel.Title = 'Initialization';
            app.NumDropDownLabel = uilabel(app.InitializationPanel);
            app.NumDropDownLabel.Text = 'Num';
            app.NumDropDown = uidropdown(app.InitializationPanel);
            app.NumDropDown.Items = {'3', '4', '5', '6', '7', '8'};
            app.NumDropDown.Value = '5';
            app.InitButton = uibutton(app.InitializationPanel, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.PlotButton = uibutton(app.InitializationPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ScaleEditFieldLabel = uilabel(app.InitializationPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.InitializationPanel, 'numeric');
            app.ScaleEditField.Limits = [0 1];
            app.ScaleEditField.Value = 0.75;
            app.PlanButton = uibutton(app.InitializationPanel, 'push');
            app.PlanButton.ButtonPushedFcn = createCallbackFcn(app, @PlanButtonPushed, true);
            app.PlanButton.Text = 'Plan';
            app.ShowButton = uibutton(app.InitializationPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.SimulationPanel = uipanel(app.MascotUIFigure);
            app.SimulationPanel.Title = 'Simulation';
            app.dtEditFieldLabel = uilabel(app.SimulationPanel);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.dtEditField.Limits = [0.001 0.1];
            app.dtEditField.Value = 0.01;
            app.StartButton = uibutton(app.SimulationPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.SimulationPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ClearButton = uibutton(app.SimulationPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ResetButton = uibutton(app.SimulationPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DimDropDownLabel = uilabel(app.SimulationPanel);
            app.DimDropDownLabel.Text = 'Dim';
            app.DimDropDown = uidropdown(app.SimulationPanel);
            app.DimDropDown.Items = {'2', '3'};
            app.DimDropDown.Value = '2';
            app.SpeedDropDownLabel = uilabel(app.SimulationPanel);
            app.SpeedDropDownLabel.Text = 'Speed';
            app.SpeedDropDown = uidropdown(app.SimulationPanel);
            app.SpeedDropDown.Items = {'1', '2', '5', '10', '20'};
            app.SpeedDropDown.Value = '1';
            app.TEditFieldLabel = uilabel(app.SimulationPanel);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.TEditField.Limits = [0 100];
            app.TEditField.Value = 100;
            app.Log = uitextarea(app.MascotUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Mascot_old

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MascotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MascotUIFigure)
        end
    end
end